classdef DiferenciacionNumerica < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        valorDeH                   matlab.ui.control.NumericEditField
        IngreselacondicininicialXoEditFieldLabel_2  matlab.ui.control.Label
        TablaErrores               matlab.ui.control.Table
        ErroresLabel               matlab.ui.control.Label
        DerivadasevualuadasLabel   matlab.ui.control.Label
        valorACalcular             matlab.ui.control.NumericEditField
        IngreselacondicininicialXoEditFieldLabel  matlab.ui.control.Label
        funcionprincipal           matlab.ui.control.EditField
        IngreselafuncinprincipalfxLabel  matlab.ui.control.Label
        volver                     matlab.ui.control.Button
        CalcularButton             matlab.ui.control.Button
        TablaDerivadas             matlab.ui.control.Table
        DiferenciacinnmericaLabel  matlab.ui.control.Label
    end

    
    methods (Access = private)
        
        function validacion = validarCampos(app)
                if ~isempty(app.funcionprincipal.Value)
                f = str2func(['@(x)' app.funcionprincipal.Value]);
                try
                disp(f(1))
                validacion=true;
                catch
                   warndlg("La funcion ingresada no es coherente o no esta en terminos de X","Advertencia")
                   validacion=false;
                end
                else
                warndlg("Por favor ingrese la funcion","Advertencia")
                validacion=false;
                end
                if app.valorDeH.Value==0
                    warndlg("El valor h no puede ser 0","Advertencia")
                    validacion=false;
                end
           
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, x)
            app.TablaDerivadas.Data =repmat({''},1, 4);
            app.TablaErrores.Data =repmat({''},1, 4);
        end

        % Button pushed function: volver
        function volverPushed(app, event)
            delete(app)
            ventanaInicio
        end

        % Button pushed function: CalcularButton
        function CalcularButtonPushed(app, event)
            if validarCampos(app) == true
                syms x;    
                f = str2func(['@(x)' app.funcionprincipal.Value]);
                primeraDerivadaReal=diff(f,x);
                segundaDerivadaReal=diff(primeraDerivadaReal,x);
                terceraDerivadaReal=diff(segundaDerivadaReal,x);
                cuartaDerivadaReal=diff(terceraDerivadaReal,x);

                xi=app.valorACalcular.Value;
                ximas1=xi+app.valorDeH.Value;
                ximas2=xi+(app.valorDeH.Value*2);
                ximas3=xi+(app.valorDeH.Value*3);
                ximenos1=xi-app.valorDeH.Value;
                ximenos2=xi-(app.valorDeH.Value*2);
                ximenos3=xi-(app.valorDeH.Value*3);

                fXi=f(xi);
                fXimas1=f(ximas1);
                fXimas2=f(ximas2);
                fXimas3=f(ximas3);
                fXimenos1=f(ximenos1);
                fXimenos2=f(ximenos2);
                fXimenos3=f(ximenos3);

                primeraDiferenciacion= (-fXimas2+8*fXimas1-8*fXimenos1+fXimenos2)/(12*app.valorDeH.Value);
                segundaDiferenciacion= (-fXimas2+16*fXimas1-30*fXi+16*fXimenos1-fXimenos2)/(12*(app.valorDeH.Value)^2);
                terceraDiferenciacion= (-fXimas3+8*fXimas2-13*fXimas1+13*fXimenos1-8*fXimenos2+fXimenos3)/(8*(app.valorDeH.Value)^3);
                cuartaDiferenciacion= (-fXimas3+12*fXimas2-39*fXimas1+56*fXi-39*fXimenos1+12*fXimenos2-fXimenos3)/(6*(app.valorDeH.Value)^4)
                nr={sprintf('%.6f',primeraDiferenciacion) 
                    sprintf('%.6f',segundaDiferenciacion) 
                    sprintf('%.6f',terceraDiferenciacion) 
                    sprintf('%.6f',cuartaDiferenciacion)};
                app.TablaDerivadas.Data ={'','','',''};
                app.TablaDerivadas.Data(end,:)=nr;
                x=app.valorACalcular.Value;
                primeraDerivadaReal=eval(primeraDerivadaReal);
                segundaDerivadaReal=eval(segundaDerivadaReal);
                terceraDerivadaReal=eval(terceraDerivadaReal);
                cuartaDerivadaReal=eval(cuartaDerivadaReal)
                nr={sprintf('%.6f',abs((primeraDerivadaReal-primeraDiferenciacion)/primeraDerivadaReal)*100) 
                    sprintf('%.6f',abs((segundaDerivadaReal-segundaDiferenciacion)/segundaDerivadaReal)*100) 
                    sprintf('%.6f',abs((terceraDerivadaReal-terceraDiferenciacion)/terceraDerivadaReal)*100) 
                    sprintf('%.6f',abs((cuartaDerivadaReal-cuartaDiferenciacion)/cuartaDerivadaReal)*100)};
                app.TablaErrores.Data ={'','','',''};
                app.TablaErrores.Data(end,:)=nr;
             end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.DiferenciacinnmericaLabel = uilabel(app.UIFigure);
            app.DiferenciacinnmericaLabel.Text = 'Diferenciación númerica';
            app.TablaDerivadas = uitable(app.UIFigure);
            app.TablaDerivadas.ColumnName = {'Primera derivada'; 'Segunda derivada'; 'Tercera derivada'; 'Cuarta derivada'};
            app.TablaDerivadas.RowName = {};
            app.TablaDerivadas.ColumnEditable = true;
            app.CalcularButton = uibutton(app.UIFigure, 'push');
            app.CalcularButton.ButtonPushedFcn = createCallbackFcn(app, @CalcularButtonPushed, true);
            app.CalcularButton.Text = 'Calcular';
            app.volver = uibutton(app.UIFigure, 'push');
            app.volver.ButtonPushedFcn = createCallbackFcn(app, @volverPushed, true);
            app.volver.Text = 'Volver';
            app.IngreselafuncinprincipalfxLabel = uilabel(app.UIFigure);
            app.IngreselafuncinprincipalfxLabel.Text = 'Ingrese la funcion:';
            app.funcionprincipal = uieditfield(app.UIFigure, 'text');
            app.IngreselacondicininicialXoEditFieldLabel = uilabel(app.UIFigure);
            app.IngreselacondicininicialXoEditFieldLabel.Text = 'Ingrese el valor que desa evaluar en las derivadas calculadas:';
            app.valorACalcular = uieditfield(app.UIFigure, 'numeric');
            app.valorACalcular.ValueDisplayFormat = '%11.6g';
            app.DerivadasevualuadasLabel = uilabel(app.UIFigure);
            app.DerivadasevualuadasLabel.Text = 'Derivadas evualuadas:';
            app.ErroresLabel = uilabel(app.UIFigure);
            app.ErroresLabel.Text = 'Errores ';
            app.TablaErrores = uitable(app.UIFigure);
            app.TablaErrores.ColumnName = {'Primera derivada'; 'Segunda derivada'; 'Tercera derivada'; 'Cuarta derivada'};
            app.TablaErrores.RowName = {};
            app.TablaErrores.ColumnEditable = true;
            app.IngreselacondicininicialXoEditFieldLabel_2 = uilabel(app.UIFigure);
            app.IngreselacondicininicialXoEditFieldLabel_2.Text = 'Valor de h:';
            app.valorDeH = uieditfield(app.UIFigure, 'numeric');
            app.valorDeH.ValueDisplayFormat = '%11.6g';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DiferenciacionNumerica(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end