classdef Falsaposicion < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        IngreseelintervaloinferiorEditField  matlab.ui.control.NumericEditField
        IngreseelintervaloinferiorEditFieldLabel  matlab.ui.control.Label
        IngreselafuncionEditField      matlab.ui.control.EditField
        IngreselafuncinEditFieldLabel  matlab.ui.control.Label
        IngreseelporcentajedeerrornormalizadodeseadoEditField  matlab.ui.control.NumericEditField
        IngreseelporcentajedeerrornormalizadodeseadoEditFieldLabel  matlab.ui.control.Label
        IngreseelintervalosuperiorEditField  matlab.ui.control.NumericEditField
        IngreseelintervalosuperiorEditFieldLabel  matlab.ui.control.Label
        SoliButton                     matlab.ui.control.Button
        CalcularButton                 matlab.ui.control.Button
        UITable                        matlab.ui.control.Table
        FalsaposicinLabel              matlab.ui.control.Label
    end

    
    methods (Access = private)
        
        function validacion = validarCampos(app)
           if app.IngreseelporcentajedeerrornormalizadodeseadoEditField.Value ~= 0
                if ~isempty(app.IngreselafuncionEditField.Value)
                f = str2func(['@(x)' app.IngreselafuncionEditField.Value]);
                try
                disp(f(3))
                validacion=true;
                catch
                   warndlg("La funcion ingresada no es coherente o no esta en terminos de X","Advertencia")
                   validacion=false;
                end
                else
                warndlg("Por favor ingrese la funcion","Advertencia")
                validacion=false;
                end
            else
              warndlg("El error normalizado deseado no puede ser 0","Advertencia")
              validacion=false;
            end 
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CalcularButton
        function CalcularButtonPushed(app, event)
            if validarCampos(app) == true
                  xai=app.IngreseelintervaloinferiorEditField.Value;
                  xbi=app.IngreseelintervalosuperiorEditField.Value;
                  errorDeseado=app.IngreseelporcentajedeerrornormalizadodeseadoEditField.Value;
                  
                  f = str2func(['@(x)' app.IngreselafuncionEditField.Value]);
                  i=1;
 
                  fa=f(xai);
                  fb=f(xbi);
 
                  errorNormalizado(i)=100;
                  if fa*fb < 0
                     xa(i)=xai; 
                     fa=f(xa(i));
                     xb(i)=xbi; 
                     fb=f(xb(i));
                     xr(i)=xb(i)-((fb*(xa(i)-xb(i)))/(fa-fb)); 
                     fr=f(xr(i));
                     
                     nr={i sprintf('%.8f',xa(i)) sprintf('%.8f',xr(i)) sprintf('%.8f',xb(i)) sprintf('%.8f',fa) sprintf('%.8f',fr) sprintf('%.8f',fb) '-'};
                     app.UITable.Data ={'','','','','','','',''};
                     app.UITable.Data(end,:)=nr;

                     while abs(errorNormalizado(i)) >= errorDeseado
                          xa(i+1)=xr(i);
                          fa=f(xa(i+1));
                          xb(i+1)=xb(i);
                          fb=f(xb(i+1)); 
                          xr(i+1)=xb(i+1)-((fb*(xa(i+1)-xb(i+1)))/(fa-fb)); 
                          fr=f(xr(i+1));
                          errorNormalizado(i+1)=abs((xr(i+1)-xr(i))/(xr(i+1))*100);
                          nr={i+1 sprintf('%.8f',xa(i+1)) sprintf('%.8f',xr(i+1)) sprintf('%.8f',xb(i+1)) sprintf('%.8f',fa) sprintf('%.8f',fr) sprintf('%.8f',fb) sprintf('%.8f',errorNormalizado(i+1))};
                          app.UITable.Data(end+1,:)=nr;
                          i=i+1;

                     end  
                  else
                     warndlg("No existe una raiz en ese intervalo","Advertencia");
                  end
            end
        end

        % Button pushed function: SoliButton
        function SoliButtonPushed(app, event)
            delete(app)
            Ventana2
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FalsaposicinLabel = uilabel(app.UIFigure);
            app.FalsaposicinLabel.Text = 'Falsa posición';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Iteración'; 'Xi'; 'Xr'; 'Xs'; 'f(Xi)'; 'f(Xr)'; 'f(Xs)'; 'Error'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = true;
            app.CalcularButton = uibutton(app.UIFigure, 'push');
            app.CalcularButton.ButtonPushedFcn = createCallbackFcn(app, @CalcularButtonPushed, true);
            app.CalcularButton.Text = 'Calcular';
            app.SoliButton = uibutton(app.UIFigure, 'push');
            app.SoliButton.ButtonPushedFcn = createCallbackFcn(app, @SoliButtonPushed, true);
            app.SoliButton.Text = 'Volver';
            app.IngreseelintervalosuperiorEditFieldLabel = uilabel(app.UIFigure);
            app.IngreseelintervalosuperiorEditFieldLabel.Text = 'Ingrese el intervalo superior:';
            app.IngreseelintervalosuperiorEditField = uieditfield(app.UIFigure, 'numeric');
            app.IngreseelporcentajedeerrornormalizadodeseadoEditFieldLabel = uilabel(app.UIFigure);
            app.IngreseelporcentajedeerrornormalizadodeseadoEditFieldLabel.Text = 'Ingrese el porcentaje de error normalizado deseado:';
            app.IngreseelporcentajedeerrornormalizadodeseadoEditField = uieditfield(app.UIFigure, 'numeric');
            app.IngreselafuncinEditFieldLabel = uilabel(app.UIFigure);
            app.IngreselafuncinEditFieldLabel.Text = 'Ingrese la función:';
            app.IngreselafuncionEditField = uieditfield(app.UIFigure, 'text');
            app.IngreseelintervaloinferiorEditFieldLabel = uilabel(app.UIFigure);
            app.IngreseelintervaloinferiorEditFieldLabel.Text = 'Ingrese el intervalo inferior:';
            app.IngreseelintervaloinferiorEditField = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Falsaposicion

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end