classdef GaussSeidel < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        GaussSeidelLabel              matlab.ui.control.Label
        NumerodeiteracionesEditField  matlab.ui.control.NumericEditField
        NumerodeiteracionesEditFieldLabel  matlab.ui.control.Label
        ErroresnormalizadosLabel      matlab.ui.control.Label
        errores                       matlab.ui.control.Table
        resultados                    matlab.ui.control.Table
        VolverButton                  matlab.ui.control.Button
        CalcularButton                matlab.ui.control.Button
        VectorcondicionesinicialesX0Label  matlab.ui.control.Label
        iniciales                     matlab.ui.control.Table
        VectorsolucionesLabel         matlab.ui.control.Label
        soluciones                    matlab.ui.control.Table
        MatrizcoeficientesLabel       matlab.ui.control.Label
        AumentarButton                matlab.ui.control.Button
        coeficientes                  matlab.ui.control.Table
        DisminuirButton               matlab.ui.control.Button
    end

    
      properties (Access = private)
        contador = 3; % Description
      end
    
        methods (Access = private)
            
           function validacion = validarCampos(app)
               validacion = true;
                   for i=1:app.contador
                        valordiagonal = string(app.coeficientes.Data(i,i));
    
                         if valordiagonal == ""
                                warndlg("La posicion [" +i+","+i+"] se encuentra vacia","Coeficientes");
                                validacion=false;
                                break; 
                         end
    
                        valordiagonalnumerico= str2num(valordiagonal);
    
                         if isempty(valordiagonalnumerico)
                                    warndlg("La posicion [" +i+","+i+"] no es un numero","Coeficientes");
                                    validacion=false; 
                                    break;
                         end
                       for j=1:app.contador
                       valor = string(app.coeficientes.Data(i,j));
                            if i~=j && valor == ""
                                warndlg("La posicion [" +i+","+j+"] se encuentra vacia","Coeficientes");
                                validacion=false;
                                break; 
                            end
                       valorNumerico= str2num(valor);
                            if i~=j && isempty(valorNumerico)
                                    warndlg("La posicion [" +i+","+j+"] no es un numero","Coeficientes");
                                    validacion=false; 
                                    break;
                            end
                           
                            if i~=j && abs(valordiagonalnumerico)<abs(valorNumerico)
                                    warndlg("La posicion [" +i+","+j+"] es mayor a la diagonal ["+i+","+i+"]","Coeficientes");
                                    validacion=false; 
                                    break;
    
                            end
                      
                       end
                       
                       if validacion == false 
                           break;
                       end
    
                       valorSoluciones = string(app.soluciones.Data(i,1));
                       valorCondicionesIniciales = string(app.iniciales.Data(i,1));
    
                        if valorSoluciones == ""
                                warndlg("La solucion [" +i+"] se encuentra vacia","Soluciones");
                                validacion=false;
                                break; 
                        end
                        if valorCondicionesIniciales == ""
                                warndlg("La condicion inicial [" +i+"] se encuentra vacia","Condiciones");
                                validacion=false;
                                break; 
                        end
    
                       valorNumericoSoluciones= str2num(valorSoluciones);
                       valorNumericoCondicionesIniciales= str2num(valorCondicionesIniciales);
                            if isempty(valorNumericoSoluciones)
                                    warndlg("La solucion [" +i+"] no es un numero","Soluciones");
                                    validacion=false; 
                                    break;
                            end
    
                            if isempty(valorNumericoCondicionesIniciales)
                                    warndlg("La condicion inicial [" +i+"] no es un numero","Condiciones");
                                    validacion=false; 
                                    break;
                            end


                   end
           end
        end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, x)
            app.coeficientes.Data =repmat({''}, app.contador, app.contador);
            app.soluciones.Data =repmat({''}, app.contador, 1);
            app.iniciales.Data =repmat({''}, app.contador, 1);
        end

        % Button pushed function: AumentarButton
        function AumentarButtonPushed(app, event)
             app.contador= app.contador +1;
            app.coeficientes.Data =repmat({''}, app.contador, app.contador);
            app.soluciones.Data =repmat({''}, app.contador, 1);
            app.iniciales.Data =repmat({''}, app.contador, 1);
            app.coeficientes.ColumnName= "X"+string(1:app.contador);
        end

        % Button pushed function: DisminuirButton
        function DisminuirButtonPushed(app, event)
            app.contador= app.contador -1;
            app.coeficientes.Data =repmat({''}, app.contador, app.contador);
            app.soluciones.Data =repmat({''}, app.contador, 1);
            app.iniciales.Data =repmat({''}, app.contador, 1);
            app.coeficientes.ColumnName= "X"+string(1:app.contador);
        end

        % Button pushed function: CalcularButton
        function CalcularButtonPushed(app, event)
              if app.validarCampos() == true
               condiciones=app.iniciales.Data;
               anteriores=condiciones;
               for iteraciones=1:app.NumerodeiteracionesEditField.Value 
                for i=1:app.contador
                      division=str2num(string(app.coeficientes.Data(i,i)));
                      solucion=str2num(string(app.soluciones.Data(i,1)));
                      x(i)=solucion;
                    for j=1:app.contador
                        if i ~= j
                            coeficiente=str2num(string(app.coeficientes.Data(i,j)));
                            condicion=str2num(string(condiciones(j,1)));
                            x(i)=x(i)-(coeficiente*condicion);
                        end
                    end
                      x(i)=x(i)/division;
                      siguientes{:,i}=x(i);
                      condiciones(i,:)= {x(i)};


                end
                if iteraciones == 1
                    nrresultados{:,1}=iteraciones;
                    nrerrores{:,1}=iteraciones;
                    for i=1:app.contador
                        nrresultados{:,i+1}=sprintf('%.6f',siguientes{:,i});
                        nrerrores{:,i+1}='-';
                    end
                    app.resultados.ColumnName= ["Iteracion","X"+string(1:app.contador)];
                    app.errores.ColumnName= ["Iteracion","X"+string(1:app.contador)];
                    app.resultados.Data=repmat({''}, 1, app.contador+1);
                    app.errores.Data=repmat({''}, 1, app.contador+1);
                    app.resultados.Data(end,:)=nrresultados;
                    app.errores.Data(end,:)=nrerrores;
                else
                    nrresultados{:,1}=iteraciones;
                    nrerrores{:,1}=iteraciones;
                    for i=1:app.contador
                        nrresultados{:,i+1}=sprintf('%.6f',siguientes{:,i});
                        anterior=str2num(string(anteriores(i,:)));
                        actual=str2num(string(condiciones(i,:)));
                        nrerrores{:,i+1}=sprintf('%.6f',abs((actual-anterior)/actual)*100);
                        anteriores(i,:)=condiciones(i,:);
                    end
                    app.resultados.Data(end+1,:)=nrresultados;
                    app.errores.Data(end+1,:)=nrerrores;
                end   
               end
            end
        end

        % Button pushed function: VolverButton
        function VolverButtonPushed(app, event)
            delete(app)
            Ventana3
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.DisminuirButton = uibutton(app.UIFigure, 'push');
            app.DisminuirButton.ButtonPushedFcn = createCallbackFcn(app, @DisminuirButtonPushed, true);
            app.DisminuirButton.Text = 'Disminuir';
            app.coeficientes = uitable(app.UIFigure);
            app.coeficientes.ColumnName = {'X1'; 'X2'; 'X3'};
            app.coeficientes.RowName = {};
            app.coeficientes.ColumnEditable = true;
            app.AumentarButton = uibutton(app.UIFigure, 'push');
            app.AumentarButton.ButtonPushedFcn = createCallbackFcn(app, @AumentarButtonPushed, true);
            app.AumentarButton.Text = 'Aumentar';
            app.MatrizcoeficientesLabel = uilabel(app.UIFigure);
            app.MatrizcoeficientesLabel.Text = 'Matriz coeficientes';
            app.soluciones = uitable(app.UIFigure);
            app.soluciones.ColumnName = {'B'};
            app.soluciones.RowName = {};
            app.soluciones.ColumnEditable = true;
            app.VectorsolucionesLabel = uilabel(app.UIFigure);
            app.VectorsolucionesLabel.Text = 'Vector soluciones';
            app.iniciales = uitable(app.UIFigure);
            app.iniciales.ColumnName = {'X0'};
            app.iniciales.RowName = {};
            app.iniciales.ColumnEditable = true;
            app.VectorcondicionesinicialesX0Label = uilabel(app.UIFigure);
            app.VectorcondicionesinicialesX0Label.Text = {'Vector condiciones iniciales '; '                   (X0)'};
            app.CalcularButton = uibutton(app.UIFigure, 'push');
            app.CalcularButton.ButtonPushedFcn = createCallbackFcn(app, @CalcularButtonPushed, true);
            app.CalcularButton.Text = 'Calcular';
            app.VolverButton = uibutton(app.UIFigure, 'push');
            app.VolverButton.ButtonPushedFcn = createCallbackFcn(app, @VolverButtonPushed, true);
            app.VolverButton.Text = 'Volver';
            app.resultados = uitable(app.UIFigure);
            app.resultados.ColumnName = '';
            app.resultados.RowName = {};
            app.errores = uitable(app.UIFigure);
            app.errores.ColumnName = '';
            app.errores.RowName = {};
            app.ErroresnormalizadosLabel = uilabel(app.UIFigure);
            app.ErroresnormalizadosLabel.Text = 'Errores normalizados';
            app.NumerodeiteracionesEditFieldLabel = uilabel(app.UIFigure);
            app.NumerodeiteracionesEditFieldLabel.Text = 'Numero de iteraciones';
            app.NumerodeiteracionesEditField = uieditfield(app.UIFigure, 'numeric');
            app.NumerodeiteracionesEditField.Limits = [1 Inf];
            app.NumerodeiteracionesEditField.ValueDisplayFormat = '%11.6g';
            app.NumerodeiteracionesEditField.Value = 1;
            app.GaussSeidelLabel = uilabel(app.UIFigure);
            app.GaussSeidelLabel.Text = 'Gauss-Seidel';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GaussSeidel(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end