classdef InterpolacionNewton < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        ValorinterpoladoEditField       matlab.ui.control.EditField
        ValorinterpoladoEditFieldLabel  matlab.ui.control.Label
        ValoresdefxLabel                matlab.ui.control.Label
        valoresFX                       matlab.ui.control.Table
        InterpolaciondeNewtonLabel      matlab.ui.control.Label
        SoliButton                      matlab.ui.control.Button
        resultadoPolinomio              matlab.ui.control.EditField
        PolinomiodeinterpolaciondeNewtonLabel  matlab.ui.control.Label
        CalcularButton                  matlab.ui.control.Button
        ValoresdeXLabel                 matlab.ui.control.Label
        AumentarButton                  matlab.ui.control.Button
        DisminuirButton                 matlab.ui.control.Button
        valorAInterpolar                matlab.ui.control.NumericEditField
        IngreseelvalorquedeseainterpolarEditFieldLabel  matlab.ui.control.Label
        valoresX                        matlab.ui.control.Table
    end

    
    properties (Access = private)
        contador=3; % Description
    end
    
    methods (Access = private)
        
                function validacion = validarCampos(app)
                       validacion = true;
        
                         for i=1:app.contador
                               valor = string(app.valoresX.Data(1,i));
                                    if valor == ""
                                        warndlg("La posicion [" +i+"] se encuentra vacia","Valores de X");
                                        validacion=false;
                                        break; 
                                    end
                               valorNumerico= str2num(valor);
                                    if isempty(valorNumerico)
                                            warndlg("La posicion [" +i+"] no es un numero","Valores de X");
                                            validacion=false; 
                                            break;
                                    end
                                  
                              
                         end
                         if(validacion==true)
                         for i=1:app.contador
                               valor = string(app.valoresFX.Data(1,i));
                                    if valor == ""
                                        warndlg("La posicion [" +i+"] se encuentra vacia","Valores de FX");
                                        validacion=false;
                                        break; 
                                    end
                               valorNumerico= str2num(valor);
                                    if isempty(valorNumerico)
                                            warndlg("La posicion [" +i+"] no es un numero","Valores de FX");
                                            validacion=false; 
                                            break;
                                    end
                                  
                              
                         end
                         end

                end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, x)
            app.valoresX.Data =repmat({''},1, app.contador);
            app.valoresFX.Data =repmat({''},1, app.contador);
        end

        % Button pushed function: AumentarButton
        function AumentarButtonPushed(app, event)
            app.contador= app.contador +1;
            app.valoresX.Data =repmat({''},1, app.contador);
            app.valoresX.ColumnName= "X"+string(0:app.contador);
            app.valoresFX.Data =repmat({''},1, app.contador);
            app.valoresFX.ColumnName= "f(x"+string(0:app.contador)+")";
        end

        % Button pushed function: DisminuirButton
        function DisminuirButtonPushed(app, event)
            app.contador= app.contador -1;
            app.valoresX.Data =repmat({''},1, app.contador);
            app.valoresX.ColumnName= "X"+string(0:app.contador);
             app.valoresFX.Data =repmat({''},1, app.contador);
            app.valoresFX.ColumnName= "f(x"+string(0:app.contador)+")";
        end

        % Button pushed function: CalcularButton
        function CalcularButtonPushed(app, event)
            if app.validarCampos() == true
                    n=app.contador;
                    x=zeros(1,n);
                    b=zeros(n);
                    for i=1:n
                         valorX = str2num(string(app.valoresX.Data(1,i)));
                         x(1,i)=valorX;
                         valorFX = str2num(string(app.valoresFX.Data(1,i)));
                         b(i,1)= valorFX;
                    end
                    disp(b);
                    %obtenemos la tabla de diferencia
                    for j=2:n
                        for i=1:n-j+1
                            b(i,j)=(b(i+1,j-1)-b(i,j-1))/(x(1,i+j-1)-x(1,i));
                        end
                    end
                    disp(b);
                  
                    %Calculamos el dato que queremos interpolar 
                    xi=app.valorAInterpolar.Value;
                    xt=1;  
                    yi=b(1,1);
                    for j=1:n-1
                        xt=xt.*(xi-x(j));
                        yi=yi+(b(1,j+i)*xt);
                    end
                    %Contruimos el polinomio
                    p=sprintf('%.6f',b(1,1));
                    xx=x*-1; 
                    
                    for j=2:n
                        signo='';
                        if  b(i,j)>=0
                            signo='+';
                        end
                     
                        xt='';
                        for i=1:j-1
                            signo2='';
                            if xx(i)>=0
                                signo2 ='+';
                            end
                            
                            xt = strcat(xt,'*(x',signo2,num2str(xx(i)),')');
                        end
                        
                        p= strcat(p,signo,sprintf('%.6f',b(1,j)),xt);
                    end
                    app.resultadoPolinomio.Value=p;
                    app.ValorinterpoladoEditField.Value=sprintf('%.6f',yi);
            end
        end

        % Button pushed function: SoliButton
        function SoliButtonPushed(app, event)
            delete(app)
            Ventana4
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.valoresX = uitable(app.UIFigure);
            app.valoresX.ColumnName = {'X0'; 'X1'; 'X2'};
            app.valoresX.RowName = {};
            app.valoresX.ColumnEditable = true;
            app.IngreseelvalorquedeseainterpolarEditFieldLabel = uilabel(app.UIFigure);
            app.IngreseelvalorquedeseainterpolarEditFieldLabel.Text = 'Ingrese el valor que desea interpolar :';
            app.valorAInterpolar = uieditfield(app.UIFigure, 'numeric');
            app.DisminuirButton = uibutton(app.UIFigure, 'push');
            app.DisminuirButton.ButtonPushedFcn = createCallbackFcn(app, @DisminuirButtonPushed, true);
            app.DisminuirButton.Text = 'Disminuir';
            app.AumentarButton = uibutton(app.UIFigure, 'push');
            app.AumentarButton.ButtonPushedFcn = createCallbackFcn(app, @AumentarButtonPushed, true);
            app.AumentarButton.Text = 'Aumentar';
            app.ValoresdeXLabel = uilabel(app.UIFigure);
            app.ValoresdeXLabel.Text = 'Valores de X:';
            app.CalcularButton = uibutton(app.UIFigure, 'push');
            app.CalcularButton.ButtonPushedFcn = createCallbackFcn(app, @CalcularButtonPushed, true);
            app.CalcularButton.Text = 'Calcular';
            app.PolinomiodeinterpolaciondeNewtonLabel = uilabel(app.UIFigure);
            app.PolinomiodeinterpolaciondeNewtonLabel.Text = 'Polinomio de interpolacion de Newton: ';
            app.resultadoPolinomio = uieditfield(app.UIFigure, 'text');
            app.SoliButton = uibutton(app.UIFigure, 'push');
            app.SoliButton.ButtonPushedFcn = createCallbackFcn(app, @SoliButtonPushed, true);
            app.SoliButton.Text = 'Volver';
            app.InterpolaciondeNewtonLabel = uilabel(app.UIFigure);
            app.InterpolaciondeNewtonLabel.Text = 'Interpolacion de Newton';
            app.valoresFX = uitable(app.UIFigure);
            app.valoresFX.ColumnName = {'f(x0)'; 'f(x1)'; 'f(x2)'};
            app.valoresFX.RowName = {};
            app.valoresFX.ColumnEditable = true;
            app.ValoresdefxLabel = uilabel(app.UIFigure);
            app.ValoresdefxLabel.Text = 'Valores de f(x) :';
            app.ValorinterpoladoEditFieldLabel = uilabel(app.UIFigure);
            app.ValorinterpoladoEditFieldLabel.Text = 'Valor interpolado';
            app.ValorinterpoladoEditField = uieditfield(app.UIFigure, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = InterpolacionNewton(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end