classdef Newtonrapshon < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        IngreselacondicininicialXoEditField  matlab.ui.control.NumericEditField
        IngreselacondicininicialXoEditFieldLabel  matlab.ui.control.Label
        funcionprincipal    matlab.ui.control.EditField
        IngreselafuncinprincipalfxLabel  matlab.ui.control.Label
        IngreseelporcentajedeerrornormalizadodeseadoEditField  matlab.ui.control.NumericEditField
        IngreseelporcentajedeerrornormalizadodeseadoEditFieldLabel  matlab.ui.control.Label
        SoliButton          matlab.ui.control.Button
        CalcularButton      matlab.ui.control.Button
        UITable             matlab.ui.control.Table
        NewtonRapshonLabel  matlab.ui.control.Label
    end

    
    methods (Access = private)
        
        function validacion = validarCampos(app)
           if app.IngreseelporcentajedeerrornormalizadodeseadoEditField.Value ~= 0
                if ~isempty(app.funcionprincipal.Value)
                f = str2func(['@(x)' app.funcionprincipal.Value]);
                try
                disp(f(1))
                validacion=true;
                catch
                   warndlg("La funcion ingresada no es coherente o no esta en terminos de X","Advertencia")
                   validacion=false;
                end
                else
                warndlg("Por favor ingrese la funcion","Advertencia")
                validacion=false;
                end
            else
              warndlg("El error normalizado deseado no puede ser 0","Advertencia")
              validacion=false;
            end 
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SoliButton
        function SoliButtonPushed(app, event)
            delete(app)
            Ventana2
        end

        % Button pushed function: CalcularButton
        function CalcularButtonPushed(app, event)
            if validarCampos(app) == true
                    syms x;
                    xfi=app.IngreselacondicininicialXoEditField.Value;
                    errorDeseado=app.IngreseelporcentajedeerrornormalizadodeseadoEditField.Value;
                    f = str2func(['@(x)' app.funcionprincipal.Value]);
                    g = diff(f(x));
                    i=1;
                    g1=subs(g,x,xfi);
                    f1=f(xfi);
                    xf(i)=xfi-(f1/g1);
                    errorNormalizado(i)=100;
                    nr={i sprintf('%.8f',xfi) sprintf('%.8f',f1) sprintf('%.8f',g1) sprintf('%.8f',xf(i)) '-'};
                    app.UITable.Data ={'','','','','',''};
                    app.UITable.Data(end,:)=nr;
                    while abs(errorNormalizado(i))>=errorDeseado
                        g1=subs(g,x,xf(i));
                        f1=f(xf(i));
                        xf(i+1) = xf(i)-(f1/g1);
                        errorNormalizado(i+1) = abs((xf(i+1)-xf(i))/xf(i+1))*100; 
                        nr={i+1 sprintf('%.8f',xf(i)) sprintf('%.8f',f1) sprintf('%.8f',g1) sprintf('%.8f',xf(i+1)) sprintf('%.8f',errorNormalizado(i+1))};
                        app.UITable.Data(end+1,:)=nr;
                        i=i+1;
                    end
                    
             end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.NewtonRapshonLabel = uilabel(app.UIFigure);
            app.NewtonRapshonLabel.Text = 'Newton-Rapshon';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Iteración'; 'Xi'; 'f(xi)'; 'f ''(xi)'; 'Xi+1'; 'Error'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = true;
            app.CalcularButton = uibutton(app.UIFigure, 'push');
            app.CalcularButton.ButtonPushedFcn = createCallbackFcn(app, @CalcularButtonPushed, true);
            app.CalcularButton.Text = 'Calcular';
            app.SoliButton = uibutton(app.UIFigure, 'push');
            app.SoliButton.ButtonPushedFcn = createCallbackFcn(app, @SoliButtonPushed, true);
            app.SoliButton.Text = 'Volver';
            app.IngreseelporcentajedeerrornormalizadodeseadoEditFieldLabel = uilabel(app.UIFigure);
            app.IngreseelporcentajedeerrornormalizadodeseadoEditFieldLabel.Text = 'Ingrese el porcentaje de error normalizado deseado:';
            app.IngreseelporcentajedeerrornormalizadodeseadoEditField = uieditfield(app.UIFigure, 'numeric');
            app.IngreselafuncinprincipalfxLabel = uilabel(app.UIFigure);
            app.IngreselafuncinprincipalfxLabel.Text = 'Ingrese la funcion:';
            app.funcionprincipal = uieditfield(app.UIFigure, 'text');
            app.IngreselacondicininicialXoEditFieldLabel = uilabel(app.UIFigure);
            app.IngreselacondicininicialXoEditFieldLabel.Text = 'Ingrese la condición inicial Xo :';
            app.IngreselacondicininicialXoEditField = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Newtonrapshon

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end