classdef Rungekutta4 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        hvalor            matlab.ui.control.NumericEditField
        IngreselacondicininicialXoEditFieldLabel_4  matlab.ui.control.Label
        tabla             matlab.ui.control.Table
        superior          matlab.ui.control.NumericEditField
        IngreselacondicininicialXoEditFieldLabel_3  matlab.ui.control.Label
        y0valor           matlab.ui.control.NumericEditField
        IngreselacondicininicialXoEditFieldLabel_2  matlab.ui.control.Label
        inferior          matlab.ui.control.NumericEditField
        IngreselacondicininicialXoEditFieldLabel  matlab.ui.control.Label
        funcionprincipal  matlab.ui.control.EditField
        IngreselafuncinprincipalfxLabel  matlab.ui.control.Label
        volver            matlab.ui.control.Button
        CalcularButton    matlab.ui.control.Button
        Simpson13Label    matlab.ui.control.Label
    end

    
    methods (Access = private)
        
        function validacion = validarCampos(app)
                if ~isempty(app.funcionprincipal.Value)
                f = str2func(['@(x,y)' app.funcionprincipal.Value]);
                try
                disp(f(1,1))
                validacion=true;
                catch
                   warndlg("La funcion ingresada no es coherente o no esta en terminos de X y Y","Advertencia")
                   validacion=false;
                end
                else
                warndlg("Por favor ingrese la funcion","Advertencia")
                validacion=false;
                end
                if app.y0valor.Value<=0
                    warndlg("El numero de intervalos tiene que ser positivo","Advertencia")
                    validacion=false;
                end
           
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

        end

        % Button pushed function: volver
        function volverPushed(app, event)
            delete(app)
            Ventana5
        end

        % Button pushed function: CalcularButton
        function CalcularButtonPushed(app, event)
            if validarCampos(app) == true
                f = str2func(['@(x,y)' app.funcionprincipal.Value]);
                a=app.inferior.Value;
                b=app.superior.Value;
                h=app.hvalor.Value;
                x=a:h:b;
                n=length(x);
                y1=app.y0valor.Value;
                y=[y1];
                app.tabla.Data ={'','',''};
                for i=1:n-1
                    k1=f(x(i),y(i));
                    k2=f(x(i)+h/2,y(i)+k1*h/2);
                    k3=f(x(i)+h/2,y(i)+k2*h/2);
                    k4=f(x(i)+h,y(i)+k3*h);
                    y(i+1)=y(i)+(h/6)*(k1+2*k2+2*k3+k4);
                    nr={num2str(i) sprintf('%.6f',x(i)) sprintf('%.6f',y(i)) };
                    if i<=1
                        app.tabla.Data(end,:)=nr;
                    else
                        app.tabla.Data(end+1,:)=nr;
                    end
                end


                plot(x,y)
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Simpson13Label = uilabel(app.UIFigure);
            app.Simpson13Label.Text = 'Simpson 1/3';
            app.CalcularButton = uibutton(app.UIFigure, 'push');
            app.CalcularButton.ButtonPushedFcn = createCallbackFcn(app, @CalcularButtonPushed, true);
            app.CalcularButton.Text = 'Calcular';
            app.volver = uibutton(app.UIFigure, 'push');
            app.volver.ButtonPushedFcn = createCallbackFcn(app, @volverPushed, true);
            app.volver.Text = 'Volver';
            app.IngreselafuncinprincipalfxLabel = uilabel(app.UIFigure);
            app.IngreselafuncinprincipalfxLabel.Text = 'Ingrese la funcion:';
            app.funcionprincipal = uieditfield(app.UIFigure, 'text');
            app.IngreselacondicininicialXoEditFieldLabel = uilabel(app.UIFigure);
            app.IngreselacondicininicialXoEditFieldLabel.Text = 'Ingrese el intervalo inferior:';
            app.inferior = uieditfield(app.UIFigure, 'numeric');
            app.inferior.ValueDisplayFormat = '%11.6g';
            app.IngreselacondicininicialXoEditFieldLabel_2 = uilabel(app.UIFigure);
            app.IngreselacondicininicialXoEditFieldLabel_2.Text = 'Ingrese el valor de y0: ';
            app.y0valor = uieditfield(app.UIFigure, 'numeric');
            app.y0valor.ValueDisplayFormat = '%11.6g';
            app.IngreselacondicininicialXoEditFieldLabel_3 = uilabel(app.UIFigure);
            app.IngreselacondicininicialXoEditFieldLabel_3.Text = 'Ingrese el intervalo superior:';
            app.superior = uieditfield(app.UIFigure, 'numeric');
            app.superior.ValueDisplayFormat = '%11.6g';
            app.tabla = uitable(app.UIFigure);
            app.tabla.ColumnName = {'i'; 'xi'; 'yi'};
            app.tabla.RowName = {};
            app.tabla.ColumnEditable = true;
            app.IngreselacondicininicialXoEditFieldLabel_4 = uilabel(app.UIFigure);
            app.IngreselacondicininicialXoEditFieldLabel_4.Text = 'Ingrese h: ';
            app.hvalor = uieditfield(app.UIFigure, 'numeric');
            app.hvalor.ValueDisplayFormat = '%11.6g';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Rungekutta4

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end