classdef SimpsonTres < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        ErrorEditField           matlab.ui.control.EditField
        ErrorEditFieldLabel      matlab.ui.control.Label
        ResultadoEditField       matlab.ui.control.EditField
        ResultadoEditFieldLabel  matlab.ui.control.Label
        superior                 matlab.ui.control.NumericEditField
        IngreselacondicininicialXoEditFieldLabel_3  matlab.ui.control.Label
        numeroIntervalos         matlab.ui.control.NumericEditField
        IngreselacondicininicialXoEditFieldLabel_2  matlab.ui.control.Label
        inferior                 matlab.ui.control.NumericEditField
        IngreselacondicininicialXoEditFieldLabel  matlab.ui.control.Label
        funcionprincipal         matlab.ui.control.EditField
        IngreselafuncinprincipalfxLabel  matlab.ui.control.Label
        volver                   matlab.ui.control.Button
        CalcularButton           matlab.ui.control.Button
        Simpson38Label           matlab.ui.control.Label
    end

    
    methods (Access = private)
        
        function validacion = validarCampos(app)
                if ~isempty(app.funcionprincipal.Value)
                f = str2func(['@(x)' app.funcionprincipal.Value]);
                try
                disp(f(1))
                validacion=true;
                catch
                   warndlg("La funcion ingresada no es coherente o no esta en terminos de X","Advertencia")
                   validacion=false;
                end
                else
                warndlg("Por favor ingrese la funcion","Advertencia")
                validacion=false;
                end
                if app.numeroIntervalos.Value<=0
                    warndlg("El numero de intervalos tiene que ser positivo","Advertencia")
                    validacion=false;
                end
           
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

        end

        % Button pushed function: volver
        function volverPushed(app, event)
            delete(app)
            Ventana5
        end

        % Button pushed function: CalcularButton
        function CalcularButtonPushed(app, event)
            if validarCampos(app) == true
                syms x;    
                f = str2func(['@(x)' app.funcionprincipal.Value]);
                a=app.inferior.Value;
                b=app.superior.Value;
                xo= f(a);
                xn=f(b);
                n=app.numeroIntervalos.Value;
                h=(b-a)/n;
                sumatoria1=0;
                sumatoria2=0;
                sumatoria3=0;
                for i=1:(n-1)
                    if(mod(i,3)==1)
                        sumatoria1=f(a+(h*i))+sumatoria1;
                    end
                    if(mod(i,3)==2)
                        sumatoria2=f(a+(h*i))+sumatoria2;
                    end
                    if(mod(i,3)==0)
                        sumatoria3=f(a+(h*i))+sumatoria3;
                    end
                end
                sumatoria1
                sumatoria2
                sumatoria3
                integralCalculada=((3*h)/8)*(xo+3*sumatoria1+3*sumatoria2+2*sumatoria3+xn);
                app.ResultadoEditField.Value=sprintf('%.6f',integralCalculada);
                expr=f(x);
                integralReal = int(expr,[a b]);
                app.ErrorEditField.Value= sprintf('%.6f',abs((integralReal-integralCalculada)/integralReal)*100);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Simpson38Label = uilabel(app.UIFigure);
            app.Simpson38Label.Text = 'Simpson 3/8';
            app.CalcularButton = uibutton(app.UIFigure, 'push');
            app.CalcularButton.ButtonPushedFcn = createCallbackFcn(app, @CalcularButtonPushed, true);
            app.CalcularButton.Text = 'Calcular';
            app.volver = uibutton(app.UIFigure, 'push');
            app.volver.ButtonPushedFcn = createCallbackFcn(app, @volverPushed, true);
            app.volver.Text = 'Volver';
            app.IngreselafuncinprincipalfxLabel = uilabel(app.UIFigure);
            app.IngreselafuncinprincipalfxLabel.Text = 'Ingrese la funcion:';
            app.funcionprincipal = uieditfield(app.UIFigure, 'text');
            app.IngreselacondicininicialXoEditFieldLabel = uilabel(app.UIFigure);
            app.IngreselacondicininicialXoEditFieldLabel.Text = 'Ingrese el intervalo inferior:';
            app.inferior = uieditfield(app.UIFigure, 'numeric');
            app.inferior.ValueDisplayFormat = '%11.6g';
            app.IngreselacondicininicialXoEditFieldLabel_2 = uilabel(app.UIFigure);
            app.IngreselacondicininicialXoEditFieldLabel_2.Text = 'Ingrese el numero de intervalos:';
            app.numeroIntervalos = uieditfield(app.UIFigure, 'numeric');
            app.numeroIntervalos.ValueDisplayFormat = '%11.6g';
            app.IngreselacondicininicialXoEditFieldLabel_3 = uilabel(app.UIFigure);
            app.IngreselacondicininicialXoEditFieldLabel_3.Text = 'Ingrese el intervalo superior:';
            app.superior = uieditfield(app.UIFigure, 'numeric');
            app.superior.ValueDisplayFormat = '%11.6g';
            app.ResultadoEditFieldLabel = uilabel(app.UIFigure);
            app.ResultadoEditFieldLabel.Text = 'Resultado:';
            app.ResultadoEditField = uieditfield(app.UIFigure, 'text');
            app.ErrorEditFieldLabel = uilabel(app.UIFigure);
            app.ErrorEditFieldLabel.Text = 'Error:';
            app.ErrorEditField = uieditfield(app.UIFigure, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SimpsonTres

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end