classdef TrapecioMultiple < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        ErrorEditField           matlab.ui.control.EditField
        ErrorEditFieldLabel      matlab.ui.control.Label
        ResultadoEditField       matlab.ui.control.EditField
        ResultadoEditFieldLabel  matlab.ui.control.Label
        superior                 matlab.ui.control.NumericEditField
        IngreselacondicininicialXoEditFieldLabel_3  matlab.ui.control.Label
        numeroTrapecios          matlab.ui.control.NumericEditField
        IngreselacondicininicialXoEditFieldLabel_2  matlab.ui.control.Label
        inferior                 matlab.ui.control.NumericEditField
        IngreselacondicininicialXoEditFieldLabel  matlab.ui.control.Label
        funcionprincipal         matlab.ui.control.EditField
        IngreselafuncinprincipalfxLabel  matlab.ui.control.Label
        volver                   matlab.ui.control.Button
        CalcularButton           matlab.ui.control.Button
        TrapecioMultipleLabel    matlab.ui.control.Label
    end

    
    methods (Access = private)
        
        function validacion = validarCampos(app)
                if ~isempty(app.funcionprincipal.Value)
                f = str2func(['@(x)' app.funcionprincipal.Value]);
                try
                disp(f(1))
                validacion=true;
                catch
                   warndlg("La funcion ingresada no es coherente o no esta en terminos de X","Advertencia")
                   validacion=false;
                end
                else
                warndlg("Por favor ingrese la funcion","Advertencia")
                validacion=false;
                end
                if app.numeroTrapecios.Value<=0
                    warndlg("El numero de intervalos tiene que ser positivo","Advertencia")
                    validacion=false;
                end
           
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

        end

        % Button pushed function: volver
        function volverPushed(app, event)
            delete(app)
            Ventana5
        end

        % Button pushed function: CalcularButton
        function CalcularButtonPushed(app, event)
            if validarCampos(app) == true
                syms x;    
                f = str2func(['@(x)' app.funcionprincipal.Value]);
                a=app.inferior.Value;
                b=app.superior.Value;
                xo= f(a);
                xn=f(b);
                n=app.numeroTrapecios.Value;
                h=(b-a)/n;
                sumatoria=0;
                for i=1:(n-1)
                    sumatoria=f(a+(h*i))+sumatoria;
                end
                integralCalculada=(h/2)*(xo+2*sumatoria+xn);
                app.ResultadoEditField.Value=sprintf('%.6f',integralCalculada);
                expr=f(x);
                integralReal = int(expr,[a b]);
                app.ErrorEditField.Value= sprintf('%.6f',abs((integralReal-integralCalculada)/integralReal)*100);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TrapecioMultipleLabel = uilabel(app.UIFigure);
            app.TrapecioMultipleLabel.Text = 'Trapecio Multiple';
            app.CalcularButton = uibutton(app.UIFigure, 'push');
            app.CalcularButton.ButtonPushedFcn = createCallbackFcn(app, @CalcularButtonPushed, true);
            app.CalcularButton.Text = 'Calcular';
            app.volver = uibutton(app.UIFigure, 'push');
            app.volver.ButtonPushedFcn = createCallbackFcn(app, @volverPushed, true);
            app.volver.Text = 'Volver';
            app.IngreselafuncinprincipalfxLabel = uilabel(app.UIFigure);
            app.IngreselafuncinprincipalfxLabel.Text = 'Ingrese la funcion:';
            app.funcionprincipal = uieditfield(app.UIFigure, 'text');
            app.IngreselacondicininicialXoEditFieldLabel = uilabel(app.UIFigure);
            app.IngreselacondicininicialXoEditFieldLabel.Text = 'Ingrese el intervalo inferior:';
            app.inferior = uieditfield(app.UIFigure, 'numeric');
            app.inferior.ValueDisplayFormat = '%11.6g';
            app.IngreselacondicininicialXoEditFieldLabel_2 = uilabel(app.UIFigure);
            app.IngreselacondicininicialXoEditFieldLabel_2.Text = 'Ingrese el numero de trapecios:';
            app.numeroTrapecios = uieditfield(app.UIFigure, 'numeric');
            app.numeroTrapecios.ValueDisplayFormat = '%11.6g';
            app.IngreselacondicininicialXoEditFieldLabel_3 = uilabel(app.UIFigure);
            app.IngreselacondicininicialXoEditFieldLabel_3.Text = 'Ingrese el intervalo superior:';
            app.superior = uieditfield(app.UIFigure, 'numeric');
            app.superior.ValueDisplayFormat = '%11.6g';
            app.ResultadoEditFieldLabel = uilabel(app.UIFigure);
            app.ResultadoEditFieldLabel.Text = 'Resultado:';
            app.ResultadoEditField = uieditfield(app.UIFigure, 'text');
            app.ErrorEditFieldLabel = uilabel(app.UIFigure);
            app.ErrorEditFieldLabel.Text = 'Error:';
            app.ErrorEditField = uieditfield(app.UIFigure, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TrapecioMultiple

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end